classdef nearestPoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        ViewMenu                  matlab.ui.container.Menu
        AreasMenu                 matlab.ui.container.Menu
        DefaultViewMenu           matlab.ui.container.Menu
        ShowAreaUnderCurvesMenu   matlab.ui.container.Menu
        ShowGoodZoneMenu          matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        PlotcircleatpointButton   matlab.ui.control.Button
        InstantCheckBox           matlab.ui.control.CheckBox
        AutoSwitch                matlab.ui.control.RockerSwitch
        AutoSwitchLabel           matlab.ui.control.Label
        closeSoft                 matlab.ui.control.Button
        closeMem                  matlab.ui.control.Button
        NewStartPoint             matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        IterationsEditField       matlab.ui.control.NumericEditField
        IterationsEditFieldLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        xmin = -10;
        xmax = 10;
        ymin = -10; 
        ymax = 10; 

        currentX;
        currentY; 
        lastX;
        lastY;

        x = 0:0.1:10; % Used for plotting, may be unneeded?

        curve_memristor;
        curve_software;

        iterations;

        autoDelay = 'false';
        autoSwitch = 'false';
    end
    
    methods (Access = private)
        
        
        function point = closestPoint(app, xin, yin, curve)
            syms x y
            dfx = sqrt((x - xin)^2 + (curve - yin)^2);
            D = diff(dfx);
            simplify(D);
            eqn = D == 0;
            dist = vpa(solve(eqn, x));
            dist(dist~=real(dist)) = NaN;
            x = dist(1);
            y = eval(curve);

            point = [x, y];
        end
        

        % menuname should be in format of "app.menu3" or whatever it's called
        % example: toggleCheckMenu(app, app.DefaultViewMenu)
        function toggleCheckMenu(app, menuName) 
            isChecked = menuName.Checked;
            if isequal(isChecked,'on')
                menuName.Checked = 'off';
            elseif isequal(isChecked,'off')
                menuName.Checked = 'on';
            end
        
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Random Curve
            syms x y
            curve1 = 0.5*x^3 - 3*x^2 + 0.4*x;
            ezcurve = x^5 + x^3 - 6;

            app.curve_memristor = ezcurve;
            app.curve_software = 0.5*x^3 -2*x^2 + 3*x - 3;

            fplot(app.UIAxes, app.curve_memristor);
            fplot(app.UIAxes, app.curve_software);
            
            app.DefaultViewMenu.Checked = 'on';
            app.ShowAreaUnderCurvesMenu.Checked = 'off';
            app.ShowGoodZoneMenu.Checked = 'off';

            app.iterations = 0;
        end

        % Button pushed function: NewStartPoint
        function NewStartPointButtonPushed(app, event)
            app.lastX = 0; 
            app.lastY = 0;
            
            app.currentX = randi([app.xmin app.xmax]);
            app.currentY = randi([app.ymin app.ymax]);
            plot(app.UIAxes, app.currentX, app.currentY, 'x', 'Color', 'black');
            app.iterations = 0;
            app.IterationsEditField.Value = app.iterations;
        end

        % Button pushed function: closeMem
        function closeMemButtonPushed(app, event)
            point = closestPoint(app, app.currentX, app.currentY, app.curve_memristor); % find the closest point
            plot(app.UIAxes, point(1), point(2), 'o', 'Color', 'black'); % Plot the point on the figure
            app.lastX = app.currentX;
            app.lastY = app.currentY;
            app.currentX = point(1);
            app.currentY = point(2);
            app.iterations = app.iterations + 1;
            app.IterationsEditField.Value = app.iterations;
        end

        % Button pushed function: closeSoft
        function closeSoftButtonPushed(app, event)
            point = closestPoint(app, app.currentX, app.currentY, app.curve_software); % find the closest point
            plot(app.UIAxes, point(1), point(2), 'o', 'Color', 'black'); % Plot the point on the figure
            app.lastX = app.currentX;
            app.lastY = app.currentY;
            app.currentX = point(1);
            app.currentY = point(2);
            app.iterations = app.iterations + 1;
            app.IterationsEditField.Value = app.iterations;
        end

        % Value changed function: InstantCheckBox
        function InstantCheckBoxValueChanged(app, event)
            value = app.InstantCheckBox.Value;
            
            app.autoDelay ~= app.autoDelay;
        end

        % Value changed function: AutoSwitch
        function AutoSwitchValueChanged(app, event)
            value = app.AutoSwitch.Value;
            
            app.autoSwitch ~= app.autoSwitch;
            if (app.autoSwitch)
                for i = 1:totalIterations % need an end condition (reach the good zone) (how about once we get stuck in a valley (two points on the same curve are "close" (how close does this need to be? experiment) it finds the midpoint between memristor and software and goes perpendicular in the correct direction the same distance)
                    % we could fill in the area under memristor with yellow and the other with
                    % blue so it creates green in the good zone (or just figure the integral to
                    % do so)
                    closeMemButtonPushed(app, 'null');
                    closeSoftButtonPushed(app, 'null');
                end
            end
        end

        % Button pushed function: PlotcircleatpointButton
        function PlotcircleatpointButtonPushed(app, event)
            x = app.lastX;
            y = app.lastY;
            r = sqrt((app.currentX - app.lastX)^2 + (app.currentY - app.lastY)^2);

            ang=0:0.01:2*pi; 
            xp=r*cos(ang);
            yp=r*sin(ang);
            plot(app.UIAxes, x+xp,y+yp);
        end

        % Menu selected function: DefaultViewMenu
        function DefaultViewMenuSelected(app, event)
            app.DefaultViewMenu.Checked = 'on';
            app.ShowAreaUnderCurvesMenu.Checked = 'off';
            app.ShowGoodZoneMenu.Checked = 'off';
        end

        % Menu selected function: ShowAreaUnderCurvesMenu
        function ShowAreaUnderCurvesMenuSelected(app, event)
            app.DefaultViewMenu.Checked = 'off';
            app.ShowAreaUnderCurvesMenu.Checked = 'on';
            app.ShowGoodZoneMenu.Checked = 'off'; 
            
            x = 0:0.1:10;
            y = solve(app.curve_memristor, x)
            area(app.UIAxes, x, y);
        end

        % Menu selected function: ShowGoodZoneMenu
        function ShowGoodZoneMenuSelected(app, event)
            app.DefaultViewMenu.Checked = 'off';
            app.ShowAreaUnderCurvesMenu.Checked = 'off';
            app.ShowGoodZoneMenu.Checked = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.AreasMenu = uimenu(app.ViewMenu);
            app.AreasMenu.Text = 'Areas';
            app.DefaultViewMenu = uimenu(app.AreasMenu);
            app.DefaultViewMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultViewMenuSelected, true);
            app.DefaultViewMenu.Checked = 'on';
            app.DefaultViewMenu.Text = 'Default View';
            app.ShowAreaUnderCurvesMenu = uimenu(app.AreasMenu);
            app.ShowAreaUnderCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowAreaUnderCurvesMenuSelected, true);
            app.ShowAreaUnderCurvesMenu.Checked = 'on';
            app.ShowAreaUnderCurvesMenu.Text = 'Show Area Under Curves';
            app.ShowGoodZoneMenu = uimenu(app.AreasMenu);
            app.ShowGoodZoneMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowGoodZoneMenuSelected, true);
            app.ShowGoodZoneMenu.Checked = 'on';
            app.ShowGoodZoneMenu.Text = 'Show "Good Zone"';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NewStartPoint = uibutton(app.LeftPanel, 'push');
            app.NewStartPoint.ButtonPushedFcn = createCallbackFcn(app, @NewStartPointButtonPushed, true);
            app.NewStartPoint.Text = 'New Starting Point';
            app.closeMem = uibutton(app.LeftPanel, 'push');
            app.closeMem.ButtonPushedFcn = createCallbackFcn(app, @closeMemButtonPushed, true);
            app.closeMem.Text = 'Go to Closest Point on Memristor Curve';
            app.closeSoft = uibutton(app.LeftPanel, 'push');
            app.closeSoft.ButtonPushedFcn = createCallbackFcn(app, @closeSoftButtonPushed, true);
            app.closeSoft.Text = 'Go to Closest Point on Software Curve';
            app.AutoSwitchLabel = uilabel(app.LeftPanel);
            app.AutoSwitchLabel.Text = 'Auto';
            app.AutoSwitch = uiswitch(app.LeftPanel, 'rocker');
            app.AutoSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoSwitchValueChanged, true);
            app.InstantCheckBox = uicheckbox(app.LeftPanel);
            app.InstantCheckBox.ValueChangedFcn = createCallbackFcn(app, @InstantCheckBoxValueChanged, true);
            app.InstantCheckBox.Text = 'Instant';
            app.PlotcircleatpointButton = uibutton(app.LeftPanel, 'push');
            app.PlotcircleatpointButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcircleatpointButtonPushed, true);
            app.PlotcircleatpointButton.Text = {'Plot circle'; 'at point'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Nearest-Point Algorithm')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.XTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.IterationsEditFieldLabel = uilabel(app.RightPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.RightPanel, 'numeric');
            app.IterationsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nearestPoint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end